classdef pewtrcon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ShowLegendCheckBox             matlab.ui.control.CheckBox
        DatatypeDropDown               matlab.ui.control.DropDown
        DatatypeDropDownLabel          matlab.ui.control.Label
        DisplayOptions                 matlab.ui.container.TabGroup
        PlottingTab                    matlab.ui.container.Tab
        EndDateDate                    matlab.ui.control.DatePicker
        EndDateDatePickerLabel         matlab.ui.control.Label
        StartDateDate                  matlab.ui.control.DatePicker
        StartDateDatePickerLabel       matlab.ui.control.Label
        AmpMaxEditPlot                 matlab.ui.control.EditField
        AmpmaxLabel                    matlab.ui.control.Label
        AmpMinEditPlot                 matlab.ui.control.EditField
        AmpminLabel                    matlab.ui.control.Label
        AmpPlotSwitch                  matlab.ui.control.Switch
        TimePlotSwitch                 matlab.ui.control.Switch
        EndtimeEditPlot                matlab.ui.control.EditField
        EndtimeEditField_2Label        matlab.ui.control.Label
        StarttimeEditPlot              matlab.ui.control.EditField
        StarttimeEditField_2Label      matlab.ui.control.Label
        CropTimeTab                    matlab.ui.container.Tab
        StartDateCrop                  matlab.ui.control.DatePicker
        EndDateLabel                   matlab.ui.control.Label
        EndDateCrop                    matlab.ui.control.DatePicker
        StartDateDatePickerLabel_2     matlab.ui.control.Label
        EndtimeEditCrop                matlab.ui.control.EditField
        EndtimeEditFieldLabel          matlab.ui.control.Label
        StarttimeEditCrop              matlab.ui.control.EditField
        StarttimeEditFieldLabel        matlab.ui.control.Label
        CroptimeButton                 matlab.ui.control.StateButton
        TimezoneEditField              matlab.ui.control.EditField
        TimezoneEditFieldLabel         matlab.ui.control.Label
        TimeStampsButton               matlab.ui.control.StateButton
        RecordedTimeButton             matlab.ui.control.StateButton
        MeanCheckBox                   matlab.ui.control.CheckBox
        ParticipantsListBox            matlab.ui.control.ListBox
        ParticipantsListBoxLabel       matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        FilterTab                      matlab.ui.container.Tab
        SGapply                        matlab.ui.control.StateButton
        SGpreview                      matlab.ui.control.StateButton
        BWapply                        matlab.ui.control.StateButton
        BWpreview                      matlab.ui.control.StateButton
        SGwinEdit                      matlab.ui.control.NumericEditField
        WindowsizesecsEditFieldLabel   matlab.ui.control.Label
        SGorderEdit                    matlab.ui.control.NumericEditField
        PolynomialorderEditFieldLabel  matlab.ui.control.Label
        butterfreq                     matlab.ui.control.NumericEditField
        LowpassFreqEditFieldLabel      matlab.ui.control.Label
        butterorder                    matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        SavitzkyGolaySmoothingFilterLabel  matlab.ui.control.Label
        LowpassButterworthFilterLabel  matlab.ui.control.Label
        EDAArtifactsTab                matlab.ui.container.Tab
        entropy                        matlab.ui.control.StateButton
        dataloss                       matlab.ui.control.StateButton
        interlosscheck                 matlab.ui.control.StateButton
        transcheck                     matlab.ui.control.StateButton
        jumpcheck                      matlab.ui.control.StateButton
        edacheck                       matlab.ui.control.StateButton
        tempcheck                      matlab.ui.control.StateButton
        interlossoverlay               matlab.ui.control.StateButton
        transoverlay                   matlab.ui.control.StateButton
        jumpoverlay                    matlab.ui.control.StateButton
        edaoverlay                     matlab.ui.control.StateButton
        tempoverlay                    matlab.ui.control.StateButton
        featuredist                    matlab.ui.control.Button
        interlosspreview               matlab.ui.control.StateButton
        transpreview                   matlab.ui.control.StateButton
        jumppreview                    matlab.ui.control.StateButton
        edapreview                     matlab.ui.control.StateButton
        temppreview                    matlab.ui.control.StateButton
        tempselect                     matlab.ui.control.DropDown
        interlossslabel                matlab.ui.control.Label
        interlossedit                  matlab.ui.control.NumericEditField
        interlosslabel                 matlab.ui.control.Label
        TimesecsLabel                  matlab.ui.control.Label
        transtime                      matlab.ui.control.NumericEditField
        translabel                     matlab.ui.control.Label
        jumplabel                      matlab.ui.control.Label
        jumpwin                        matlab.ui.control.NumericEditField
        SlopewindowmsLabel             matlab.ui.control.Label
        jumpchange                     matlab.ui.control.NumericEditField
        MaxsignalchangeSsEditFieldLabel_2  matlab.ui.control.Label
        edamax                         matlab.ui.control.NumericEditField
        RangeSLabel_2                  matlab.ui.control.Label
        edamin                         matlab.ui.control.NumericEditField
        edalabel                       matlab.ui.control.Label
        tempmin                        matlab.ui.control.NumericEditField
        tempmax                        matlab.ui.control.NumericEditField
        RangeCLabel                    matlab.ui.control.Label
        templabel                      matlab.ui.control.Label
        ManualArtifactsTab             matlab.ui.container.Tab
        AlignTimeseriesTab             matlab.ui.container.Tab
        RepeatedMeasuresTab            matlab.ui.container.Tab
        GroupingTab                    matlab.ui.container.Tab
        DeconvolutionTab               matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        ledalabpush                    matlab.ui.control.Button
        smoothwinedit                  matlab.ui.control.NumericEditField
        SmoothingwindowwidthinsamplesEditFieldLabel  matlab.ui.control.Label
        InterfacewithLedalabLabel      matlab.ui.control.Label
        SmoothingDropDown              matlab.ui.control.DropDown
        SmoothingDropDownLabel         matlab.ui.control.Label
        FileMenu                       matlab.ui.container.Menu
        ImportrawdataMenu              matlab.ui.container.Menu
        EmpaticaformatMenu             matlab.ui.container.Menu
        ProcessingMenu                 matlab.ui.container.Menu
        FilterMenu                     matlab.ui.container.Menu
        EDAArtifactsMenu               matlab.ui.container.Menu
        ManualArtifactsMenu            matlab.ui.container.Menu
        AlignTimeseriesMenu            matlab.ui.container.Menu
        RepeatedMeasuresMenu           matlab.ui.container.Menu
        GroupingMenu                   matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        pewtr2=[]; % Data
        dispdat=[]; %displayinformation
        disptime=[]; %displaytime
    end

    methods (Access = private)

        function [] = p_updatedisplay(app,~) %update display information
            disp([datestr(datetime('now')) ' - Call to update plot'])

            %Used only if UIAxes is removed
            if ~isvalid(app.UIAxes)
                delete(app.CenterPanel.Children);
                app.UIAxes=uiaxes(app.CenterPanel);
                app.UIAxes.Position= [app.UIAxes.Position(1:2) ...
                    (app.CenterPanel.Position(3:4) - 2*app.UIAxes.Position(1:2))];
            end

            app.UIAxes.YDir='normal'; %imagesc will flip this, so reset

            %Turn off preview modes
            app.temppreview.Value=0;
            app.interlosspreview.Value=0;
            app.transpreview.Value=0;
            app.jumppreview.Value=0;
            app.edapreview.Value=0;
            app.dataloss.Value=0;
            app.entropy.Value=0;

            % Get Time for Chosen DataType
            if app.RecordedTimeButton.Value==1
                displaytime=minutes(app.disptime.([app.DatatypeDropDown.Value 'mins']));
                displaytime.Format='hh:mm:ss';
                app.UIAxes.XLabel.String='Time (Recording Time)';
            elseif app.TimeStampsButton.Value==1
                displaytime=datetime(app.disptime.(app.DatatypeDropDown.Value),'ConvertFrom', ...
                    'posixtime', 'TimeZone', app.TimezoneEditField.Value);
                app.UIAxes.XLabel.String='Time (Time Stamps)';
            end

            %Display Time
            if strcmp(app.TimePlotSwitch.Value,'Default') %default timerange
                t1=1; t2=numel(displaytime);
            else
                switch app.RecordedTimeButton.Value
                    case 1 %recorded time
                        [~,t1]=min(abs(displaytime-duration(app.StarttimeEditPlot.Value)));
                        [~,t2]=min(abs(displaytime-duration(app.EndtimeEditPlot.Value)));
                    case 0 %time stamps
                        [~,t1]=min(abs(displaytime-datetime([datestr(app.StartDateDate.Value) ...
                            app.StarttimeEditPlot.Value],'InputFormat',...
                            'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', ...
                            app.TimezoneEditField.Value)));
                        [~,t2]=min(abs(displaytime-datetime([datestr(app.EndDateDate.Value) ...
                            app.EndtimeEditPlot.Value],'InputFormat',...
                            'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', ...
                            app.TimezoneEditField.Value)));
                end
            end

            %% Get data for display
            displaydata=app.dispdat.(app.DatatypeDropDown.Value);
            displaydata=displaydata(:,app.ParticipantsListBox.Value);
            app.UIAxes.YLabel.String=[app.DatatypeDropDown.Value ' Amplitude'];

            %% Preview Filter Mode
            if app.SGpreview.Value==1
                [pdata,x,y] = p_filtering(displaydata,'SG',...
                    app.SGorderEdit.Value,app.SGwinEdit.Value,...
                    app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']));
                app.SGorderEdit.Value=x;
                app.SGwinEdit.Value=y;
            elseif app.BWpreview.Value==1
                [pdata] = p_filtering(displaydata,'Butter',...
                    app.butterorder.Value,app.butterfreq.Value, ...
                    app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']));
            else
                pdata=[];
            end

            %% Preview EDA Artifacts
            dinx=[];
            if strcmpi(app.DatatypeDropDown.Value,'eda')
                if app.tempoverlay.Value==1 %temp range
                    disp([datestr(datetime('now')) ' - Processing artifact detection'])
                    [dinx]=p_artifact(displaydata,...
                        app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']), ...
                        'temp',...
                        app.dispdat.(app.tempselect.Value)(t1:t2,app.ParticipantsListBox.Value),...
                        app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']), ...
                        [app.tempmin.Value,app.tempmax.Value]);
                    disp([datestr(datetime('now')) ' - Artifact detection complete'])
                elseif app.edaoverlay.Value==1 %eda range
                    [dinx]=p_artifact(displaydata,...
                        app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']), ...
                        'range',[app.edamin.Value,app.edamax.Value]);
                elseif app.jumpoverlay.Value==1 %jumpartifacts
                    [dinx]=p_artifact(displaydata,...
                        app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']), ...
                        'jump',app.jumpwin.Value,app.jumpchange.Value);
                elseif app.transoverlay.Value==1 %transition
                    [dinx]=p_artifact(displaydata,...
                        app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']), ...
                        'trans',app.transtime.Value);
                elseif app.interlossoverlay.Value==1 %interloss period
                    [dinx]=p_artifact(displaydata,...
                        app.pewtr2.file.Hz.([app.DatatypeDropDown.Value 'hz']), ...
                        'interloss',app.interlossedit.Value);
                end
            end

            %% Average
            if app.MeanCheckBox.Value==1
                displaydata=mean(displaydata,2,'omitnan');
                if ~isempty(pdata)
                    pdata=mean(pdata,2,'omitnan');
                end
            end


            %% Plot data simple
            set(app.UIAxes,'XTickMode','auto','XTickLabelMode','auto',...
                'YTickMode','auto','YTickLabelMode','auto');
            plot(app.UIAxes,displaytime(t1:t2),displaydata(t1:t2,:));
            app.UIAxes.ColorOrder=lines;
            if ~isempty(pdata) %preview filter data plot
                app.UIAxes.NextPlot='Add';
                plot(app.UIAxes,displaytime(t1:t2),pdata(t1:t2,:),'--r');
            end

            %Amp range
            if strcmp(app.AmpPlotSwitch.Value,'Custom')
                app.UIAxes.YLim=[str2double(app.AmpMinEditPlot.Value),...
                    str2double(app.AmpMaxEditPlot.Value)];
            end

            %Artifact preview plot
            if ~isempty(dinx)
                app.UIAxes.NextPlot='Add';
                dinx=sum(dinx,3);
                dinx(:)=~dinx;
                [~,col]=size(dinx);
                Y=diff([ones(1,col) ; dinx ; ...
                    ones(1,col) ]); %pad, ~isnan, diff
                [S,~]=find(Y==-1);
                S(S<t1)=t1; S(S>t2)=t2;
                [E,~]=find(Y==1);
                E(E<t1)=t1; E(E>t2)=t2;
                x=[[displaytime(S)' displaytime(E)'] ...
                    fliplr([displaytime(S)' displaytime(E)'])]';
                y=zeros(size(x));
                y(1:2,:)=app.UIAxes.YLim(1);
                y(3:4,:)=app.UIAxes.YLim(2);
                patch(app.UIAxes,x,y,'red','FaceAlpha',.1,'EdgeAlpha',0);
            end

            if app.ShowLegendCheckBox.Value==1
                if app.MeanCheckBox.Value==1
                    legend(app.UIAxes,'Mean (Selected Subjects)')
                else
                    legend(app.UIAxes,app.ParticipantsListBox.Items(app.ParticipantsListBox.Value));
                end
            else
                legend(app.UIAxes,'off')
            end

            app.UIAxes.NextPlot='replacechildren';
            pewtr1=app.pewtr2;
            dispdat1=app.dispdat;
            disptime1=app.disptime;
            assignin('base','pewtr2',pewtr1);
            assignin('base','dispdat',dispdat1);
            assignin('base','disptime',disptime1);
            disp([datestr(datetime('now')) ' - Plotting complete - no errors'])
        end %end updatedisplay function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function p_displayrefresh(app,~)
            %% Fill lists
            app.DatatypeDropDown.Items=fieldnames(app.pewtr2.data);
            app.tempselect.Items=fieldnames(app.pewtr2.data);
            app.ParticipantsListBox.Items=app.pewtr2.subj;
            app.ParticipantsListBox.ItemsData=1:numel(app.pewtr2.subj);
            %Select defaults
            app.DatatypeDropDown.Value=app.DatatypeDropDown.Items(1); %datatype
            app.ParticipantsListBox.Value=1; %participant
            app.MeanCheckBox.Value=0; %mean checkbox
            app.RecordedTimeButton.Value=1;
            app.TimeStampsButton.Value=0;
            app.StartDateDate.Visible=0;
            app.EndDateDate.Visible=0;
            app.TimePlotSwitch.Value='Default';
            app.AmpPlotSwitch.Value='Default';
            app.CroptimeButton.Value=0;
            displaytime=minutes(eval(['app.pewtr2.time.' ...
                app.DatatypeDropDown.Value 'mins']));
            displaytime.Format='hh:mm:ss';
            app.StarttimeEditPlot.Value=datestr(displaytime(1),'HH:MM:SS.FFF');
            app.StarttimeEditCrop.Value=datestr(displaytime(1),'HH:MM:SS.FFF');
            app.EndtimeEditCrop.Value=datestr(displaytime(end),'HH:MM:SS.FFF');
            app.EndtimeEditPlot.Value=datestr(displaytime(end),'HH:MM:SS.FFF');
            ddata=eval(['app.pewtr2.data.' app.DatatypeDropDown.Value]);
            app.AmpMinEditPlot.Value=num2str(round(min(min(ddata)),2));
            app.AmpMaxEditPlot.Value=num2str(round(max(max(ddata)),2));

            %% add in filter options

        end %end display refresh function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function p_preprocess(app) %Run preprocessing steps on displaydat
            D=app.DatatypeDropDown.Items; %datatypes
            app.dispdat=app.pewtr2.data; %reload original data
            app.disptime=app.pewtr2.time; %reload original timeseries
            % 1 - Crop Time / 2 - Remove Subjects
            for dd=1:numel(D) %apply to each datatype each datatype
                if ~isempty(app.pewtr2.process{dd,1})
                    disp([datestr(datetime('now')) ' - Cropping data'])
                    t=app.pewtr2.process{dd,1}; %get crop times
                    app.disptime.([D{dd} 'mins'])=app.pewtr2.time.([D{dd} 'mins'])(t(1):t(2));
                    app.disptime.(D{dd})=app.pewtr2.time.(D{dd})(t(1):t(2));
                    app.dispdat.(D{dd})=app.dispdat.(D{dd})(t(1):t(2),:);
                end
                if ~isempty(app.pewtr2.process{dd,2}) %get subject removal
                    app.dispdat.(D{dd})(:,app.pewtr2.process{dd,2})=NaN; %remove subjects
                end
            end
            % 3 - Filter Data
            if sum(~cellfun(@isempty,app.pewtr2.process(:,3)))~=0 %find filter settings
                disp([datestr(datetime('now')) ' - Applying Filters'])
                for dd=find(~cellfun(@isempty,app.pewtr2.process(:,3)))'
                    [app.dispdat.(D{dd}),app.pewtr2.process{dd,3}{2},...
                        app.pewtr2.process{dd,3}{3}]=...
                        p_filtering(app.dispdat.(D{dd}),...
                        app.pewtr2.process{dd,3}{:}, ...
                        app.pewtr2.file.Hz.([D{dd} 'hz']));
                end
            end
            % 4 - temp range
            if sum(~cellfun(@isempty,app.pewtr2.process(:,4)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: TEMP range'])
                dd=find(~cellfun(@isempty,app.pewtr2.process(:,4)))';
                ee=strcmpi(app.pewtr2.process{dd,4}{1},D);
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'temp',app.dispdat.(D{ee}),...
                    app.pewtr2.process{dd,4}{2}, ...
                    app.pewtr2.process{dd,4}{3});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
            end
            % 5 - EDA range
            if sum(~cellfun(@isempty,app.pewtr2.process(:,5)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: EDA range'])
                dd=find(~cellfun(@isempty,app.pewtr2.process(:,5)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'range',app.pewtr2.process{dd,5});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
            end
            % 6 - jump artifacts
            if sum(~cellfun(@isempty,app.pewtr2.process(:,6)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: Jumps'])
                dd=find(~cellfun(@isempty,app.pewtr2.process(:,6)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'jump',app.pewtr2.process{dd,6}{:});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
            end
            % 7 - transitional period
            if sum(~cellfun(@isempty,app.pewtr2.process(:,7)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: Transition periods'])
                dd=find(~cellfun(@isempty,app.pewtr2.process(:,7)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'trans',app.pewtr2.process{dd,7});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
            end
            % 8 - interloss period
            if sum(~cellfun(@isempty,app.pewtr2.process(:,8)))~=0 %find settings
                disp([datestr(datetime('now')) ' - Remove artifacts: Interloss periods'])
                dd=find(~cellfun(@isempty,app.pewtr2.process(:,8)))';
                [dinx]=p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']), ...
                    'interloss',app.pewtr2.process{dd,8});
                app.dispdat.(D{dd})(logical(sum(dinx,3)))=NaN;
            end
            disp([datestr(datetime('now')) ' - Preprocessing complete'])
        end %end p_process

    end %end private methods


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: EmpaticaformatMenu
        function EmpaticaformatMenuSelected(app, event)
            sdir=uigetdir(cd,'Select Folder Containing Subjects');
            app.pewtr2=p_importempatica(sdir);
            app.dispdat=app.pewtr2.data;
            app.disptime=app.pewtr2.time;
            p_displayrefresh(app,event);
            p_updatedisplay(app, event);
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: ParticipantsListBox
        function ParticipantsListBoxValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: RecordedTimeButton
        function RecordedTimeButtonValueChanged(app, event)
            value = app.RecordedTimeButton.Value;
            if value==1
                if app.TimeStampsButton.Value==1
                    app.TimeStampsButton.Value=0;
                    displaytime=datetime(eval(['app.disptime.' ...
                        app.DatatypeDropDown.Value]),'ConvertFrom', ...
                        'posixtime', 'TimeZone', app.TimezoneEditField.Value);
                    t1=datetime([datestr(app.StartDateDate.Value) ...
                        app.StarttimeEditPlot.Value],'InputFormat',...
                        'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', app.TimezoneEditField.Value);
                    t2=datetime([datestr(app.EndDateDate.Value) ...
                        app.EndtimeEditPlot.Value],'InputFormat',...
                        'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', app.TimezoneEditField.Value);
                    t3=datetime([datestr(app.StartDateCrop.Value) ...
                        app.StarttimeEditCrop.Value],'InputFormat',...
                        'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', app.TimezoneEditField.Value);
                    t4=datetime([datestr(app.EndDateCrop.Value) ...
                        app.EndtimeEditCrop.Value],'InputFormat',...
                        'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', app.TimezoneEditField.Value);
                    t1=displaytime==t1;
                    t2=displaytime==t2;
                    t3=displaytime==t3;
                    t4=displaytime==t4;
                    displaytime=minutes(eval(['app.disptime.' ...
                        app.DatatypeDropDown.Value 'mins']));
                    displaytime.Format='hh:mm:ss.SSS';
                    app.StarttimeEditPlot.Value=datestr(displaytime(t1),'hh:MM:ss.FFF');
                    app.EndtimeEditPlot.Value=datestr(displaytime(t2),'hh:MM:ss.FFF');
                    app.StarttimeEditCrop.Value=datestr(displaytime(t3),'hh:MM:ss.FFF');
                    app.EndtimeEditCrop.Value=datestr(displaytime(t4),'hh:MM:ss.FFF');
                    app.StartDateDate.Visible=0;
                    app.EndDateDate.Visible=0;
                    app.StartDateCrop.Visible=0;
                    app.EndDateCrop.Visible=0;
                end
            elseif value==0
                app.RecordedTimeButton.Value=1;
            end
            p_updatedisplay(app, event);
        end

        % Value changed function: TimeStampsButton
        function TimeStampsButtonValueChanged(app, event)
            value = app.TimeStampsButton.Value;
            if value==1
                app.RecordedTimeButton.Value=0;
                displaytime=minutes(eval(['app.disptime.' ...
                    app.DatatypeDropDown.Value 'mins']));
                displaytime.Format='hh:mm:ss.SSS';
                t1=displaytime==duration(app.StarttimeEditPlot.Value);
                t2=displaytime==duration(app.EndtimeEditPlot.Value);
                t3=displaytime==duration(app.StarttimeEditCrop.Value);
                t4=displaytime==duration(app.EndtimeEditCrop.Value);
                displaytime=datetime(eval(['app.disptime.' ...
                    app.DatatypeDropDown.Value]),'ConvertFrom', ...
                    'posixtime', 'TimeZone', app.TimezoneEditField.Value);
                app.StarttimeEditPlot.Value=datestr(displaytime(t1),'hh:MM:ss.FFF');
                app.EndtimeEditPlot.Value=datestr(displaytime(t2),'hh:MM:ss.FFF');
                app.StarttimeEditCrop.Value=datestr(displaytime(t3),'hh:MM:ss.FFF');
                app.EndtimeEditCrop.Value=datestr(displaytime(t4),'hh:MM:ss.FFF');
                app.StartDateDate.Value=displaytime(t1);
                app.EndDateDate.Value=displaytime(t2);
                app.StartDateCrop.Value=displaytime(t3);
                app.EndDateCrop.Value=displaytime(t4);
                app.StartDateDate.Visible=1;
                app.EndDateDate.Visible=1;
                app.StartDateCrop.Visible=1;
                app.EndDateCrop.Visible=1;
            elseif app.RecordedTimeButton.Value==0
                app.TimeStampsButton.Value=1;
            end
            p_updatedisplay(app, event);
        end

        % Value changed function: TimezoneEditField
        function TimezoneEditFieldValueChanged(app, event)
            t=timezones;
            t=[t{:,1}];
            if iempty(find(strcmpi(app.TimeZoneEditField.Value,t)))
                errordlg(['Invalid Timezone entered. Please use the format ' ...
                    '"Country/City", e.g., "Australia/Brisbane". Type in ' ...
                    '"timezones" in the command window to see valid ' ...
                    'timezones'],'Error - Invalid Timezone');
                app.TimeZoneEditField.Value='Australia/Brisbane';
                return
            end
            p_updatedisplay(app, event);
        end

        % Value changed function: MeanCheckBox
        function MeanCheckBoxValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: CroptimeButton
        function CroptimeButtonValueChanged(app, event)
            value = app.CroptimeButton.Value;
            D=app.DatatypeDropDown.Items;
            for dd=1:numel(D)
                if value==1 %crop time values
                    switch app.RecordedTimeButton.Value
                        case 1 %if crop times in recorded time
                            temp=minutes(app.pewtr2.time.([D{dd} 'mins']));
                            [~,app.pewtr2.process{dd,1}(1)]...
                                =min(abs(temp-duration(app.StarttimeEditCrop.Value)));
                            [~,app.pewtr2.process{dd,1}(2)]...
                                =min(abs(temp-duration(app.EndtimeEditCrop.Value)));
                            app.StarttimeEditPlot.Value=app.StarttimeEditCrop.Value;
                            app.EndtimeEditPlot.Value=app.EndtimeEditCrop.Value;
                        case 0 %if crop times in time stamps
                            temp=datetime(app.pewtr2.time.(D{dd}),'ConvertFrom', ...
                                'posixtime', 'TimeZone', app.TimezoneEditField.Value);
                            [~,app.pewtr2.process{dd,1}(1)]...
                                =min(abs(temp-datetime([datestr(app.StartDateCrop.Value) ...
                                app.StarttimeEditCrop.Value],'InputFormat',...
                                'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', ...
                                app.TimezoneEditField.Value)));
                            [~,app.pewtr2.process{dd,1}(2)]...
                                =min(abs(temp-datetime([datestr(app.EndDateCrop.Value) ...
                                app.EndtimeEditCrop.Value],'InputFormat',...
                                'dd-MMM-yyyyHH:mm:ss.SSS', 'TimeZone', ...
                                app.TimezoneEditField.Value)));
                            app.StarttimeEditPlot.Value=app.StarttimeEditCrop.Value;
                            app.EndtimeEditPlot.Value=app.EndtimeEditCrop.Value;
                            app.StartDateDate.Value=app.StartDateCrop.Value;
                            app.EndDateDate.Value=app.EndDateCrop.Value;
                    end
                else %if 'uncropping'
                    app.pewtr2.process{dd,1}=[];
                end
            end %end dd cycle
            p_preprocess(app);
            p_updatedisplay(app, event);
        end

        % Value changed function: TimePlotSwitch
        function TimePlotSwitchValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: AmpPlotSwitch
        function AmpPlotSwitchValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: SGpreview
        function SGpreviewValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: SGapply
        function SGapplyValueChanged(app, event)
            dt=strcmp(app.DatatypeDropDown.Value,...
                app.DatatypeDropDown.Items);
            if app.SGapply.Value==1 %turned on
                app.pewtr2.process{dt,3}={'SG',app.SGorderEdit.Value,...
                    app.SGwinEdit.Value};

                %switch off other filters
                app.BWapply.Value=0;

            else %turned off
                app.pewtr2.process{dt,3}=[];

            end
            p_preprocess(app);
            p_updatedisplay(app);
        end

        % Value changed function: BWpreview
        function BWpreviewValueChanged(app, event)
            p_updatedisplay(app, event);
        end

        % Value changed function: interlosspreview
        function interlosspreviewValueChanged(app, event)
            if app.interlosspreview.Value==1
                app.transpreview.Value=0;
                app.jumppreview.Value=0;
                app.edapreview.Value=0;
                app.temppreview.Value=0;
                app.entropy.Value=0;
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                [output] = p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']),...
                    'interloss',app.interlossedit.Value);
                A=sum(output,3);
                A=movmean(A,[0 app.pewtr2.file.Hz.([D{dd} 'hz'])*10]);
                A=downsample(A, app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                A=A>0;
                B=downsample(app.disptime.([D{dd} 'mins']), app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                B=minutes(B);
                B.Format='hh:mm:ss';

                %heatmap mode
                %             delete(app.UIAxes);
                %             map=[1 1 1 ; 1 0 0];
                %             heatmap(app.CenterPanel,double(A)','Colormap',map,'GridVisible','off','XData',B(1:10:end));

                %imagesc mode
                if sum(sum(A))==0
                    map=[1 1 1 ; 1 1 1];
                else
                    map=[1 1 1 ; 1 0 0];
                end
                imagesc(app.UIAxes,double(A)');
                colormap(app.UIAxes,map);
                xt=get(app.UIAxes,'XTick');
                xtnew=linspace(min(B),max(B),numel(xt));
                set(app.UIAxes,'Xtick',xt,'XTickLabel',string(xtnew),...
                    'Ytick',1:numel(app.ParticipantsListBox.Items),...
                    'YTickLabel',app.ParticipantsListBox.Items)
                ylabel(app.UIAxes,'Participants');
                xlabel(app.UIAxes,'Recording time');
                legend(app.UIAxes,'off');

            else %reload regular display
                p_updatedisplay(app);
            end
        end

        % Value changed function: transpreview
        function transpreviewValueChanged(app, event)
            if app.transpreview.Value==1
                app.interlosspreview.Value=0;
                app.jumppreview.Value=0;
                app.edapreview.Value=0;
                app.temppreview.Value=0;
                app.entropy.Value=0;
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                [output] = p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']),...
                    'trans',app.transtime.Value);
                A=sum(output,3);
                A=movmean(A,[0 app.pewtr2.file.Hz.([D{dd} 'hz'])*10]);
                A=downsample(A, app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                A=A>0;
                B=downsample(app.disptime.([D{dd} 'mins']), app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                B=minutes(B);
                B.Format='hh:mm:ss';

                if sum(sum(A))==0
                    map=[1 1 1 ; 1 1 1];
                else
                    map=[1 1 1 ; 1 0 0];
                end
                imagesc(app.UIAxes,double(A)');
                colormap(app.UIAxes,map);
                xt=get(app.UIAxes,'XTick');
                xtnew=linspace(min(B),max(B),numel(xt));
                set(app.UIAxes,'Xtick',xt,'XTickLabel',string(xtnew),...
                    'Ytick',1:numel(app.ParticipantsListBox.Items),...
                    'YTickLabel',app.ParticipantsListBox.Items)
                ylabel(app.UIAxes,'Participants');
                xlabel(app.UIAxes,'Recording time');
                legend(app.UIAxes,'off');

            else %reload regular display
                p_updatedisplay(app);
            end
        end

        % Value changed function: jumppreview
        function jumppreviewValueChanged(app, event)
            if app.jumppreview.Value==1
                app.interlosspreview.Value=0;
                app.transpreview.Value=0;
                app.edapreview.Value=0;
                app.temppreview.Value=0;
                app.entropy.Value=0;
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                [output] = p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']),...
                    'jump',app.jumpwin.Value,app.jumpchange.Value);
                A=sum(output,3);
                A=movmean(A,[0 app.pewtr2.file.Hz.([D{dd} 'hz'])*10]);
                A=downsample(A, app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                A=A>0;
                B=downsample(app.disptime.([D{dd} 'mins']), app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                B=minutes(B);
                B.Format='hh:mm:ss';

                if sum(sum(A))==0
                    map=[1 1 1 ; 1 1 1];
                else
                    map=[1 1 1 ; 1 0 0];
                end
                imagesc(app.UIAxes,double(A)');
                colormap(app.UIAxes,map);
                xt=get(app.UIAxes,'XTick');
                xtnew=linspace(min(B),max(B),numel(xt));
                set(app.UIAxes,'Xtick',xt,'XTickLabel',string(xtnew),...
                    'Ytick',1:numel(app.ParticipantsListBox.Items),...
                    'YTickLabel',app.ParticipantsListBox.Items)
                ylabel(app.UIAxes,'Participants');
                xlabel(app.UIAxes,'Recording time');
                legend(app.UIAxes,'off');

            else %reload regular display
                p_updatedisplay(app);
            end
        end

        % Value changed function: edapreview
        function edapreviewValueChanged(app, event)
            if  app.edapreview.Value==1
                app.interlosspreview.Value=0;
                app.transpreview.Value=0;
                app.jumppreview.Value=0;
                app.temppreview.Value=0;
                app.entropy.Value=0;
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                [output] = p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']),...
                    'range',[app.edamin.Value,app.edamax.Value]);
                A=sum(output,3);
                A=movmean(A,[0 app.pewtr2.file.Hz.([D{dd} 'hz'])*10]);
                A=downsample(A, app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                A=A>0;
                B=downsample(app.disptime.([D{dd} 'mins']), app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                B=minutes(B);
                B.Format='hh:mm:ss';

                if sum(sum(A))==0
                    map=[1 1 1 ; 1 1 1];
                else
                    map=[1 1 1 ; 1 0 0];
                end
                imagesc(app.UIAxes,double(A)');
                colormap(app.UIAxes,map);
                xt=get(app.UIAxes,'XTick');
                xtnew=linspace(min(B),max(B),numel(xt));
                set(app.UIAxes,'Xtick',xt,'XTickLabel',string(xtnew),...
                    'Ytick',1:numel(app.ParticipantsListBox.Items),...
                    'YTickLabel',app.ParticipantsListBox.Items)
                ylabel(app.UIAxes,'Participants');
                xlabel(app.UIAxes,'Recording time');
                legend(app.UIAxes,'off');

            else %reload regular display
                p_updatedisplay(app);
            end
        end

        % Value changed function: temppreview
        function temppreviewValueChanged(app, event)
            if  app.temppreview.Value==1
                app.interlosspreview.Value=0;
                app.transpreview.Value=0;
                app.jumppreview.Value=0;
                app.edapreview.Value=0;
                app.entropy.Value=0;
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                [output] = p_artifact(app.dispdat.(D{dd}),...
                    app.pewtr2.file.Hz.([D{dd} 'hz']),...
                    'temp',app.dispdat.(D{ee}),...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]);
                A=sum(output,3);
                A=movmean(A,[0 app.pewtr2.file.Hz.([D{dd} 'hz'])*10]);
                A=downsample(A, app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                A=A>0;
                B=downsample(app.disptime.([D{dd} 'mins']), app.pewtr2.file.Hz.([D{dd} 'hz'])*10);
                B=minutes(B);
                B.Format='hh:mm:ss';

                if sum(sum(A))==0
                    map=[1 1 1 ; 1 1 1];
                else
                    map=[1 1 1 ; 1 0 0];
                end
                imagesc(app.UIAxes,double(A)');
                colormap(app.UIAxes,map);
                xt=get(app.UIAxes,'XTick');
                xtnew=linspace(min(B),max(B),numel(xt));
                set(app.UIAxes,'Xtick',xt,'XTickLabel',string(xtnew),...
                    'Ytick',1:numel(app.ParticipantsListBox.Items),...
                    'YTickLabel',app.ParticipantsListBox.Items)
                ylabel(app.UIAxes,'Participants');
                xlabel(app.UIAxes,'Recording time');
                legend(app.UIAxes,'off');

            else %reload regular display
                p_updatedisplay(app);
            end
        end

        % Button pushed function: featuredist
        function featuredistButtonPushed(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            featfig=subplot(2,2,1);
            histogram(featfig,app.dispdat.(D{dd})(:),20);
            title('EDA');
            xlabel('EDA (μS)');
            ylabel('Occurences');

            featfig=subplot(2,2,2);
            ds=app.pewtr2.file.Hz.([D{dd} 'hz'])*...
                (app.jumpwin.Value/1000); %window size in data samples calculation
            if ceil(ds)~=ds
                errordlg(['Selected window length for slope calculation is too large for' ...
                    ' sampling rate. Window length adjusted to ' ...
                    num2str(ceil(ds)/app.pewtr2.file.Hz.([D{dd} 'hz'])) ...
                    'ms (' num2str(ceil(ds)) ' data points)'],'Artifact Eval Warning');
                ds=ceil(ds);
                app.jumpwin.Value=(ds/app.pewtr2.file.Hz.([D{dd} 'hz']))*1000;
            end
            data=app.dispdat.(D{dd});
            jcount=zeros(size(data));
            jcount(end-ds+1:end,:)=[];
            for ii=1:numel(data(1,:)) %for each subject
                for jj=1:numel(jcount(:,ii)) %for each datapoint
                    jcount(jj,ii)=mean(abs(data(jj,ii)-data(jj+1:jj+ds,ii)),'omitnan');
                end
            end
            histogram(featfig,jcount(:),20);
            title('Jump Artifacts');
            xlabel('EDA Slope (μS/sec)');
            ylabel('Occurences');


            featfig=subplot(2,2,3);
            ee=strcmpi(D,app.tempselect.Value);
            histogram(featfig,app.dispdat.(D{ee})(:),20);
            title('Temperature');
            xlabel('Temperature (°C)');
            ylabel('Occurences');

            featfig=subplot(2,2,4);
            ff=strcmpi(D,'acc');
            histogram(featfig,app.dispdat.(D{ff})(:),20);
            title('Accelerometer');
            xlabel('Acceleration (ENMO)');
            ylabel('Occurences');

        end

        % Value changed function: tempoverlay
        function tempoverlayValueChanged(app, event)
            if app.tempoverlay.Value==1
                app.edaoverlay.Value=0;
                app.jumpoverlay.Value=0;
                app.transoverlay.Value=0;
                app.interlossoverlay.Value=0;
            end
            p_updatedisplay(app);
        end

        % Value changed function: edaoverlay
        function edaoverlayValueChanged(app, event)
            if app.edaoverlay.Value==1
                app.tempoverlay.Value=0;
                app.jumpoverlay.Value=0;
                app.transoverlay.Value=0;
                app.interlossoverlay.Value=0;
            end
            p_updatedisplay(app);
        end

        % Value changed function: jumpoverlay
        function jumpoverlayValueChanged(app, event)
            if app.jumpoverlay.Value==1
                app.edaoverlay.Value=0;
                app.tempoverlay.Value=0;
                app.transoverlay.Value=0;
                app.interlossoverlay.Value=0;
            end
            p_updatedisplay(app);
        end

        % Value changed function: transoverlay
        function transoverlayValueChanged(app, event)
            if app.transoverlay.Value==1
                app.edaoverlay.Value=0;
                app.jumpoverlay.Value=0;
                app.tempoverlay.Value=0;
                app.interlossoverlay.Value=0;
            end
            p_updatedisplay(app);
        end

        % Value changed function: interlossoverlay
        function interlossoverlayValueChanged(app, event)
            if app.interlossoverlay.Value==1
                app.edaoverlay.Value=0;
                app.jumpoverlay.Value=0;
                app.transoverlay.Value=0;
                app.tempoverlay.Value=0;
            end
            p_updatedisplay(app);
        end

        % Value changed function: tempselect
        function tempselectValueChanged(app, event)
            if app.tempcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: tempcheck
        function tempcheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.tempcheck.Value=0;
                return
            end
            if app.tempcheck.Value==1
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                app.tempcheck.Text='On';
                app.tempcheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,4}=[];
                app.tempcheck.Text='Off';
                app.tempcheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocess(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: edacheck
        function edacheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.edacheck.Value=0;
                return
            end
            if app.edacheck.Value==1
                app.pewtr2.process(dd,5)={...
                    [app.edamin.Value,app.edamax.Value]};
                app.edacheck.Text='On';
                app.edacheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,5}=[];
                app.edacheck.Text='Off';
                app.edacheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocess(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: jumpcheck
        function jumpcheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.jumpcheck.Value=0;
                return
            end
            if app.jumpcheck.Value==1
                app.pewtr2.process{dd,6}={...
                    app.jumpwin.Value,app.jumpchange.Value};
                app.jumpcheck.Text='On';
                app.jumpcheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,6}=[];
                app.jumpcheck.Text='Off';
                app.jumpcheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocess(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: transcheck
        function transcheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.transcheck.Value=0;
                return
            end
            if app.transcheck.Value==1
                app.pewtr2.process(dd,7)={app.transtime.Value};
                app.transcheck.Text='On';
                app.transcheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,7}=[];
                app.transcheck.Text='Off';
                app.transcheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocess(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: interlosscheck
        function interlosscheckValueChanged(app, event)
            D=app.DatatypeDropDown.Items; %datatypes
            dd=strcmpi(D,'eda');
            if sum(dd)==0 %check for EDA data
                errordlg(['No EDA data found in datatypes available. '...
                    'Please ensure correct labelling of datatypes']);
                app.interlosscheck.Value=0;
                return
            end
            if app.interlosscheck.Value==1
                app.pewtr2.process(dd,8)={app.interlossedit.Value};
                app.interlosscheck.Text='On';
                app.interlosscheck.BackgroundColor=[0.95,0.95,1];
            else
                app.pewtr2.process{dd,8}=[];
                app.interlosscheck.Text='Off';
                app.interlosscheck.BackgroundColor=[1,0.95,0.95];
            end
            p_preprocess(app);
            if app.dataloss.Value==1
                datalossValueChanged(app);
            elseif app.entropy.Value==1
                entropyValueChanged(app);
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            p_updatedisplay(app);
        end

        % Value changed function: tempmin
        function tempminValueChanged(app, event)
            if app.tempcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: tempmax
        function tempmaxValueChanged(app, event)
            if app.tempcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                ee=strcmpi(D,app.tempselect.Value); %find datatype
                app.pewtr2.process{dd,4}={D{ee},...
                    app.pewtr2.file.Hz.([D{ee} 'hz']),...
                    [app.tempmin.Value,app.tempmax.Value]};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: edamin
        function edaminValueChanged(app, event)
            if app.edacheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,5)={...
                    [app.edamin.Value,app.edamax.Value]};
                app.edacheck.Text='On';
                app.edacheck.BackgroundColor=[0.95,0.95,1];
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: edamax
        function edamaxValueChanged(app, event)
            if app.edacheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,5)={...
                    [app.edamin.Value,app.edamax.Value]};
                app.edacheck.Text='On';
                app.edacheck.BackgroundColor=[0.95,0.95,1];
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: jumpwin
        function jumpwinValueChanged(app, event)
            if app.jumpcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process{dd,6}={...
                    app.jumpwin.Value,app.jumpchange.Value};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: jumpchange
        function jumpchangeValueChanged(app, event)
            if app.jumpcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process{dd,6}={...
                    app.jumpwin.Value,app.jumpchange.Value};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: transtime
        function transtimeValueChanged(app, event)
            if app.transcheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,7)={app.transtime.Value};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: interlossedit
        function interlosseditValueChanged(app, event)
            if app.interlosscheck.Value==1
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                app.pewtr2.process(dd,8)={app.interlossedit.Value};
                p_preprocess(app);
                if app.dataloss.Value==1
                    datalossValueChanged(app);
                elseif app.entropy.Value==1
                    entropyValueChanged(app);
                else
                    p_updatedisplay(app);
                end
            end
        end

        % Value changed function: dataloss
        function datalossValueChanged(app, event)
            if app.dataloss.Value==1
                app.entropy.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                urdata=app.pewtr2.data.(D{dd});
                if ~isempty(app.pewtr2.process{dd,1}) %crop times
                    t=app.pewtr2.process{dd,1}; %get crop times
                    urdata=urdata(t(1):t(2),:);
                end
                if ~isempty(app.pewtr2.process{dd,2}) %subject removal
                    urdata(:,app.pewtr2.process{dd,2})=NaN; %remove subjects
                end
                [row,~]=size(urdata);
                E2=sum(~isnan(app.dispdat.(D{dd})))/row;
                E1=(sum(~isnan(urdata))/row)-E2;

                bar(app.UIAxes,categorical(app.ParticipantsListBox.Items),...
                    [E2'.*100,E1'.*100],'stacked');
                app.UIAxes.YDir='normal';
                ylabel(app.UIAxes,'Data Present (%)');
                xlabel(app.UIAxes,'Participants');
                legend(app.UIAxes,'Post Artifact removal',...
                    'Pre Artifact Removal');
            else
                p_updatedisplay(app);
            end
        end

        % Value changed function: entropy
        function entropyValueChanged(app, event)
            if app.entropy.Value==1
                app.dataloss.Value=0;
                D=app.DatatypeDropDown.Items; %datatypes
                dd=strcmpi(D,'eda');
                urdata=app.pewtr2.data.(D{dd});
                hz=app.pewtr2.file.Hz.([D{dd} 'hz']);
                if ~isempty(app.pewtr2.process{dd,1}) %crop times
                    t=app.pewtr2.process{dd,1}; %get crop times
                    urdata=urdata(t(1):t(2),:);
                end
                if ~isempty(app.pewtr2.process{dd,2}) %subject removal
                    urdata(:,app.pewtr2.process{dd,2})=NaN; %remove subjects
                end
                if sum(~cellfun(@isempty,app.pewtr2.process(:,3)))~=0 %filter
                    [urdata]=p_filtering(urdata,...
                        app.pewtr2.process{dd,3}{:},hz);
                end
                urdata=normalize(urdata);
                [row,col]=size(urdata);
                E1=NaN(size(urdata));
                for ii=1:col
                    for jj=1:10*hz:(row-rem(row,10*hz))
                        E1(jj,ii)=wentropy(urdata(jj:jj+10*hz,ii),'shannon');
                    end
                end
                data=normalize(app.dispdat.(D{dd}));
                [row,col]=size(data);
                E2=NaN(size(data));
                for ii=1:col
                    for jj=1:10*hz:(row-rem(row,10*hz))
                        E2(jj,ii)=wentropy(data(jj:jj+10*hz,ii),'shannon');
                    end
                end
                E1=mean(E1,'omitnan');
                E2=mean(E2,'omitnan');

                bar(app.UIAxes,categorical(app.ParticipantsListBox.Items),...
                    [E1;E2]);
                app.UIAxes.YDir='reverse';
                ylabel(app.UIAxes,'Shannon Entropy');
                xlabel(app.UIAxes,'Participants');
                legend(app.UIAxes,'Pre Artifact removal',...
                    'Post Artifact Removal');
            else
                p_updatedisplay(app);
            end

        end

        % Menu selected function: FilterMenu
        function FilterMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
        end

        % Menu selected function: EDAArtifactsMenu
        function EDAArtifactsMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.EDAArtifactsTab;
        end

        % Menu selected function: ManualArtifactsMenu
        function ManualArtifactsMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.ManualArtifactsTab;
        end

        % Menu selected function: AlignTimeseriesMenu
        function AlignTimeseriesMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.AlignTimeseriesTab;
        end

        % Menu selected function: RepeatedMeasuresMenu
        function RepeatedMeasuresMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.RepeatedMeasuresTab;
        end

        % Menu selected function: GroupingMenu
        function GroupingMenuSelected(app, event)
            app.TabGroup.SelectedTab=app.GroupingTab;
        end

        % Button pushed function: ledalabpush
        function ledalabpushButtonPushed(app, event)


           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %
           %
           %
           %EDIT
           %
           %
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %% Initial checks and variables
            if ~exist('Ledalab','file')
                ButtonName = questdlg(['ERROR: Ledalab could not be found on the MATLAB ' ...
                    'path. Please ensure Ledalab (www.ledalab.de) is installed and all subdirectories are ' ...
                    'added to the matlab path.'],'Ledalab not found','Okay','Okay');
                disp('Continuous decomposition analysis cancelled - Ledalab not found on path');
                return
            end
            begindir=cd; %get current directory
            
            %% Make temp directory
[success,~,~]=mkdir(begindir,'sochro_temp_mat');
if success==0
    errordlg(['Error: temporary file creation for CDA ' ...
        'failed. Check permissions for the current directory'],'ERROR');
    return
end

%% Allocate data to temporary files
inx=NaN(length(EDA(1,:)),2);
ledalist=1:length(EDA(1,:));
for ii=1:length(EDA(1,:))
    if isempty(find(~isnan(EDA(:,ii)),1,'first')) %check for EDA data
        ledalist(ii)=0;
        continue
    end
    inx(ii,1)=find(~isnan(EDA(:,ii)),1,'first');
    inx(ii,2)=find(~isnan(EDA(:,ii)),1,'last');
    data = [];
    data.conductance=EDA(inx(ii,1):inx(ii,2),ii);
    data.conductance(isnan(data.conductance))=0;
    data.time=0:1/Hz:(length(data.conductance)/Hz)-1/Hz;
    data.timeoff=0;
    data.event=[];
    save([begindir fs 'sochro_temp_mat' fs num2str(ii) '.mat'],'data');
end

%% RUN LEDALAB BATCHMODE
for ii=1:length(EDA(1,:))
    if ledalist(ii)~=0
    disp(['RUNNING CDA - DATASET ' num2str(ii)]);
    Ledalab([begindir fs 'sochro_temp_mat' fs num2str(ii) '.mat'] ,...
        'open','mat','analyze','CDA','optimize',4,'export_scrlist',[.05 1])
    end
end
cd(begindir);

%% Return data
tonic=NaN(size(EDA));
phasic=NaN(size(EDA));
PR=struct;
for ii=1:length(EDA(1,:))
    if ledalist(ii)==0
       continue 
    end
    load([begindir fs 'sochro_temp_mat' fs num2str(ii) '.mat'],'analysis');
    tonic(inx(ii,1):inx(ii,2),ii)=analysis.tonicData';
    phasic(inx(ii,1):inx(ii,2),ii)=analysis.phasicData';
    ix=tonic(:,ii)<0;
    tonic(ix,ii)=NaN;
    ix=phasic(:,ii)<0;
    phasic(ix,ii)=NaN;
    ix=find(isnan(EDA(:,ii)));
    tonic(ix,ii)=NaN;
    phasic(ix,ii)=NaN;
    
    load([begindir fs 'sochro_temp_mat' fs num2str(ii) '_scrlist.mat'],'scrList');
    PR(ii).CDA=scrList.CDA;
    PR(ii).TTP=scrList.TTP;
end
pause(1);
try
rmdir([begindir fs 'sochro_temp_mat'],'s');
catch
    warning('Temporary directory could not be removed');
end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {643, 643, 643};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {643, 643};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x', 313};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticipantsListBoxLabel = uilabel(app.LeftPanel);
            app.ParticipantsListBoxLabel.Text = 'Participants';
            app.ParticipantsListBox = uilistbox(app.LeftPanel);
            app.ParticipantsListBox.Multiselect = 'on';
            app.ParticipantsListBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantsListBoxValueChanged, true);
            app.ParticipantsListBox.Value = {'Item 1'};
            app.MeanCheckBox = uicheckbox(app.LeftPanel);
            app.MeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanCheckBoxValueChanged, true);
            app.MeanCheckBox.Text = 'Mean';
            app.RecordedTimeButton = uibutton(app.LeftPanel, 'state');
            app.RecordedTimeButton.ValueChangedFcn = createCallbackFcn(app, @RecordedTimeButtonValueChanged, true);
            app.RecordedTimeButton.Text = 'Recorded Time';
            app.RecordedTimeButton.Value = true;
            app.TimeStampsButton = uibutton(app.LeftPanel, 'state');
            app.TimeStampsButton.ValueChangedFcn = createCallbackFcn(app, @TimeStampsButtonValueChanged, true);
            app.TimeStampsButton.Text = 'Time Stamps';
            app.TimezoneEditFieldLabel = uilabel(app.LeftPanel);
            app.TimezoneEditFieldLabel.Text = 'Timezone';
            app.TimezoneEditField = uieditfield(app.LeftPanel, 'text');
            app.TimezoneEditField.ValueChangedFcn = createCallbackFcn(app, @TimezoneEditFieldValueChanged, true);
            app.TimezoneEditField.Value = 'Australia/Brisbane';
            app.DisplayOptions = uitabgroup(app.LeftPanel);
            app.PlottingTab = uitab(app.DisplayOptions);
            app.PlottingTab.Title = 'Plotting';
            app.StarttimeEditField_2Label = uilabel(app.PlottingTab);
            app.StarttimeEditField_2Label.Text = 'Start time';
            app.StarttimeEditPlot = uieditfield(app.PlottingTab, 'text');
            app.EndtimeEditField_2Label = uilabel(app.PlottingTab);
            app.EndtimeEditField_2Label.Text = 'End time';
            app.EndtimeEditPlot = uieditfield(app.PlottingTab, 'text');
            app.TimePlotSwitch = uiswitch(app.PlottingTab, 'slider');
            app.TimePlotSwitch.Items = {'Default', 'Custom'};
            app.TimePlotSwitch.ValueChangedFcn = createCallbackFcn(app, @TimePlotSwitchValueChanged, true);
            app.TimePlotSwitch.Value = 'Default';
            app.AmpPlotSwitch = uiswitch(app.PlottingTab, 'slider');
            app.AmpPlotSwitch.Items = {'Default', 'Custom'};
            app.AmpPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @AmpPlotSwitchValueChanged, true);
            app.AmpPlotSwitch.Value = 'Default';
            app.AmpminLabel = uilabel(app.PlottingTab);
            app.AmpminLabel.Text = 'Amp min';
            app.AmpMinEditPlot = uieditfield(app.PlottingTab, 'text');
            app.AmpmaxLabel = uilabel(app.PlottingTab);
            app.AmpmaxLabel.Text = 'Amp max';
            app.AmpMaxEditPlot = uieditfield(app.PlottingTab, 'text');
            app.StartDateDatePickerLabel = uilabel(app.PlottingTab);
            app.StartDateDatePickerLabel.Visible = 'off';
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDate = uidatepicker(app.PlottingTab);
            app.StartDateDate.DisplayFormat = 'dd.MM.uuuu';
            app.StartDateDate.Visible = 'off';
            app.EndDateDatePickerLabel = uilabel(app.PlottingTab);
            app.EndDateDatePickerLabel.Visible = 'off';
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDate = uidatepicker(app.PlottingTab);
            app.EndDateDate.DisplayFormat = 'dd.MM.uuuu';
            app.EndDateDate.Visible = 'off';
            app.CropTimeTab = uitab(app.DisplayOptions);
            app.CropTimeTab.Title = 'Crop Time';
            app.CroptimeButton = uibutton(app.CropTimeTab, 'state');
            app.CroptimeButton.ValueChangedFcn = createCallbackFcn(app, @CroptimeButtonValueChanged, true);
            app.CroptimeButton.Text = 'Crop time';
            app.StarttimeEditFieldLabel = uilabel(app.CropTimeTab);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditCrop = uieditfield(app.CropTimeTab, 'text');
            app.EndtimeEditFieldLabel = uilabel(app.CropTimeTab);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditCrop = uieditfield(app.CropTimeTab, 'text');
            app.StartDateDatePickerLabel_2 = uilabel(app.CropTimeTab);
            app.StartDateDatePickerLabel_2.Visible = 'off';
            app.StartDateDatePickerLabel_2.Text = 'Start Date';
            app.EndDateCrop = uidatepicker(app.CropTimeTab);
            app.EndDateCrop.DisplayFormat = 'dd.MM.uuuu';
            app.EndDateCrop.Visible = 'off';
            app.EndDateLabel = uilabel(app.CropTimeTab);
            app.EndDateLabel.Visible = 'off';
            app.EndDateLabel.Text = 'End Date';
            app.StartDateCrop = uidatepicker(app.CropTimeTab);
            app.StartDateCrop.DisplayFormat = 'dd.MM.uuuu';
            app.StartDateCrop.Visible = 'off';
            app.DatatypeDropDownLabel = uilabel(app.LeftPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.LeftPanel);
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.ShowLegendCheckBox = uicheckbox(app.LeftPanel);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = {'Show'; 'Legend'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.LowpassButterworthFilterLabel = uilabel(app.FilterTab);
            app.LowpassButterworthFilterLabel.Text = 'Low-pass Butterworth Filter';
            app.SavitzkyGolaySmoothingFilterLabel = uilabel(app.FilterTab);
            app.SavitzkyGolaySmoothingFilterLabel.Text = 'Savitzky-Golay Smoothing Filter';
            app.OrderEditFieldLabel = uilabel(app.FilterTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.butterorder = uieditfield(app.FilterTab, 'numeric');
            app.butterorder.Value = 1;
            app.LowpassFreqEditFieldLabel = uilabel(app.FilterTab);
            app.LowpassFreqEditFieldLabel.Text = 'Lowpass Freq';
            app.butterfreq = uieditfield(app.FilterTab, 'numeric');
            app.butterfreq.Value = 0.6;
            app.PolynomialorderEditFieldLabel = uilabel(app.FilterTab);
            app.PolynomialorderEditFieldLabel.Text = 'Polynomial order';
            app.SGorderEdit = uieditfield(app.FilterTab, 'numeric');
            app.SGorderEdit.Value = 3;
            app.WindowsizesecsEditFieldLabel = uilabel(app.FilterTab);
            app.WindowsizesecsEditFieldLabel.Text = 'Window size (secs)';
            app.SGwinEdit = uieditfield(app.FilterTab, 'numeric');
            app.SGwinEdit.Value = 5;
            app.BWpreview = uibutton(app.FilterTab, 'state');
            app.BWpreview.ValueChangedFcn = createCallbackFcn(app, @BWpreviewValueChanged, true);
            app.BWpreview.Text = 'Preview';
            app.BWapply = uibutton(app.FilterTab, 'state');
            app.BWapply.Text = 'Apply';
            app.SGpreview = uibutton(app.FilterTab, 'state');
            app.SGpreview.ValueChangedFcn = createCallbackFcn(app, @SGpreviewValueChanged, true);
            app.SGpreview.Text = 'Preview';
            app.SGapply = uibutton(app.FilterTab, 'state');
            app.SGapply.ValueChangedFcn = createCallbackFcn(app, @SGapplyValueChanged, true);
            app.SGapply.Text = 'Apply';
            app.EDAArtifactsTab = uitab(app.TabGroup);
            app.EDAArtifactsTab.Title = 'EDA Artifacts';
            app.templabel = uilabel(app.EDAArtifactsTab);
            app.templabel.Text = 'Temperature Range';
            app.RangeCLabel = uilabel(app.EDAArtifactsTab);
            app.RangeCLabel.Text = 'Range (°C)';
            app.tempmax = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.tempmax.ValueChangedFcn = createCallbackFcn(app, @tempmaxValueChanged, true);
            app.tempmax.Value = 40;
            app.tempmin = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.tempmin.ValueChangedFcn = createCallbackFcn(app, @tempminValueChanged, true);
            app.tempmin.Value = 30;
            app.edalabel = uilabel(app.EDAArtifactsTab);
            app.edalabel.Text = 'EDA Range';
            app.edamin = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.edamin.ValueChangedFcn = createCallbackFcn(app, @edaminValueChanged, true);
            app.edamin.Value = 0.05;
            app.RangeSLabel_2 = uilabel(app.EDAArtifactsTab);
            app.RangeSLabel_2.Text = 'Range (µS)';
            app.edamax = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.edamax.ValueChangedFcn = createCallbackFcn(app, @edamaxValueChanged, true);
            app.edamax.Value = 60;
            app.MaxsignalchangeSsEditFieldLabel_2 = uilabel(app.EDAArtifactsTab);
            app.MaxsignalchangeSsEditFieldLabel_2.Text = 'Max signal change (μS/s)';
            app.jumpchange = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.jumpchange.ValueChangedFcn = createCallbackFcn(app, @jumpchangeValueChanged, true);
            app.jumpchange.Value = 10;
            app.SlopewindowmsLabel = uilabel(app.EDAArtifactsTab);
            app.SlopewindowmsLabel.Text = 'Slope window (ms)';
            app.jumpwin = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.jumpwin.ValueChangedFcn = createCallbackFcn(app, @jumpwinValueChanged, true);
            app.jumpwin.Value = 250;
            app.jumplabel = uilabel(app.EDAArtifactsTab);
            app.jumplabel.Text = 'Jump Artifacts';
            app.translabel = uilabel(app.EDAArtifactsTab);
            app.translabel.Text = 'Transitional Period';
            app.transtime = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.transtime.ValueChangedFcn = createCallbackFcn(app, @transtimeValueChanged, true);
            app.transtime.Value = 5;
            app.TimesecsLabel = uilabel(app.EDAArtifactsTab);
            app.TimesecsLabel.Text = 'Time (secs)';
            app.interlosslabel = uilabel(app.EDAArtifactsTab);
            app.interlosslabel.Text = 'Inter-loss period';
            app.interlossedit = uieditfield(app.EDAArtifactsTab, 'numeric');
            app.interlossedit.ValueChangedFcn = createCallbackFcn(app, @interlosseditValueChanged, true);
            app.interlossedit.Value = 6;
            app.interlossslabel = uilabel(app.EDAArtifactsTab);
            app.interlossslabel.Text = 'Minimum epoch length (s)';
            app.tempselect = uidropdown(app.EDAArtifactsTab);
            app.tempselect.ValueChangedFcn = createCallbackFcn(app, @tempselectValueChanged, true);
            app.temppreview = uibutton(app.EDAArtifactsTab, 'state');
            app.temppreview.ValueChangedFcn = createCallbackFcn(app, @temppreviewValueChanged, true);
            app.temppreview.Text = 'Detect';
            app.edapreview = uibutton(app.EDAArtifactsTab, 'state');
            app.edapreview.ValueChangedFcn = createCallbackFcn(app, @edapreviewValueChanged, true);
            app.edapreview.Text = 'Detect';
            app.jumppreview = uibutton(app.EDAArtifactsTab, 'state');
            app.jumppreview.ValueChangedFcn = createCallbackFcn(app, @jumppreviewValueChanged, true);
            app.jumppreview.Text = 'Detect';
            app.transpreview = uibutton(app.EDAArtifactsTab, 'state');
            app.transpreview.ValueChangedFcn = createCallbackFcn(app, @transpreviewValueChanged, true);
            app.transpreview.Text = 'Detect';
            app.interlosspreview = uibutton(app.EDAArtifactsTab, 'state');
            app.interlosspreview.ValueChangedFcn = createCallbackFcn(app, @interlosspreviewValueChanged, true);
            app.interlosspreview.Text = 'Detect';
            app.featuredist = uibutton(app.EDAArtifactsTab, 'push');
            app.featuredist.ButtonPushedFcn = createCallbackFcn(app, @featuredistButtonPushed, true);
            app.featuredist.Text = 'Plot data feature distribution';
            app.tempoverlay = uibutton(app.EDAArtifactsTab, 'state');
            app.tempoverlay.ValueChangedFcn = createCallbackFcn(app, @tempoverlayValueChanged, true);
            app.tempoverlay.Text = 'Overlay';
            app.edaoverlay = uibutton(app.EDAArtifactsTab, 'state');
            app.edaoverlay.ValueChangedFcn = createCallbackFcn(app, @edaoverlayValueChanged, true);
            app.edaoverlay.Text = 'Overlay';
            app.jumpoverlay = uibutton(app.EDAArtifactsTab, 'state');
            app.jumpoverlay.ValueChangedFcn = createCallbackFcn(app, @jumpoverlayValueChanged, true);
            app.jumpoverlay.Text = 'Overlay';
            app.transoverlay = uibutton(app.EDAArtifactsTab, 'state');
            app.transoverlay.ValueChangedFcn = createCallbackFcn(app, @transoverlayValueChanged, true);
            app.transoverlay.Text = 'Overlay';
            app.interlossoverlay = uibutton(app.EDAArtifactsTab, 'state');
            app.interlossoverlay.ValueChangedFcn = createCallbackFcn(app, @interlossoverlayValueChanged, true);
            app.interlossoverlay.Text = 'Overlay';
            app.tempcheck = uibutton(app.EDAArtifactsTab, 'state');
            app.tempcheck.ValueChangedFcn = createCallbackFcn(app, @tempcheckValueChanged, true);
            app.tempcheck.Text = 'Off';
            app.edacheck = uibutton(app.EDAArtifactsTab, 'state');
            app.edacheck.ValueChangedFcn = createCallbackFcn(app, @edacheckValueChanged, true);
            app.edacheck.Text = 'Off';
            app.jumpcheck = uibutton(app.EDAArtifactsTab, 'state');
            app.jumpcheck.ValueChangedFcn = createCallbackFcn(app, @jumpcheckValueChanged, true);
            app.jumpcheck.Text = 'Off';
            app.transcheck = uibutton(app.EDAArtifactsTab, 'state');
            app.transcheck.ValueChangedFcn = createCallbackFcn(app, @transcheckValueChanged, true);
            app.transcheck.Text = 'Off';
            app.interlosscheck = uibutton(app.EDAArtifactsTab, 'state');
            app.interlosscheck.ValueChangedFcn = createCallbackFcn(app, @interlosscheckValueChanged, true);
            app.interlosscheck.Text = 'Off';
            app.dataloss = uibutton(app.EDAArtifactsTab, 'state');
            app.dataloss.ValueChangedFcn = createCallbackFcn(app, @datalossValueChanged, true);
            app.dataloss.Text = 'Plot Data Loss';
            app.entropy = uibutton(app.EDAArtifactsTab, 'state');
            app.entropy.ValueChangedFcn = createCallbackFcn(app, @entropyValueChanged, true);
            app.entropy.Text = 'Plot Shannon Entropy';
            app.ManualArtifactsTab = uitab(app.TabGroup);
            app.ManualArtifactsTab.Title = 'Manual Artifacts';
            app.AlignTimeseriesTab = uitab(app.TabGroup);
            app.AlignTimeseriesTab.Title = 'Align Timeseries';
            app.RepeatedMeasuresTab = uitab(app.TabGroup);
            app.RepeatedMeasuresTab.Title = 'Repeated Measures';
            app.GroupingTab = uitab(app.TabGroup);
            app.GroupingTab.Title = 'Grouping';
            app.DeconvolutionTab = uitab(app.TabGroup);
            app.DeconvolutionTab.Title = 'Deconvolution';
            app.SmoothingDropDownLabel = uilabel(app.DeconvolutionTab);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.DeconvolutionTab);
            app.SmoothingDropDown.Items = {'None', 'Moving average', 'Hanning window', 'Gauss window', 'Adaptive Smoothing'};
            app.SmoothingDropDown.Value = 'None';
            app.InterfacewithLedalabLabel = uilabel(app.DeconvolutionTab);
            app.InterfacewithLedalabLabel.Text = 'Interface with Ledalab';
            app.SmoothingwindowwidthinsamplesEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.SmoothingwindowwidthinsamplesEditFieldLabel.Text = 'Smoothing window width in samples';
            app.smoothwinedit = uieditfield(app.DeconvolutionTab, 'numeric');
            app.ledalabpush = uibutton(app.DeconvolutionTab, 'push');
            app.ledalabpush.ButtonPushedFcn = createCallbackFcn(app, @ledalabpushButtonPushed, true);
            app.ledalabpush.Tag = 'ledalabpush';
            app.ledalabpush.Text = 'Run CDA in Ledalab';
            app.Label = uilabel(app.DeconvolutionTab);
            app.Label.Text = 'Note: filters will be applied prior to continuous deconvolution analysis';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportrawdataMenu = uimenu(app.FileMenu);
            app.ImportrawdataMenu.Text = 'Import raw data';
            app.EmpaticaformatMenu = uimenu(app.ImportrawdataMenu);
            app.EmpaticaformatMenu.MenuSelectedFcn = createCallbackFcn(app, @EmpaticaformatMenuSelected, true);
            app.EmpaticaformatMenu.Text = 'Empatica format';
            app.ProcessingMenu = uimenu(app.UIFigure);
            app.ProcessingMenu.Text = 'Processing';
            app.FilterMenu = uimenu(app.ProcessingMenu);
            app.FilterMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterMenuSelected, true);
            app.FilterMenu.Text = 'Filter';
            app.EDAArtifactsMenu = uimenu(app.ProcessingMenu);
            app.EDAArtifactsMenu.MenuSelectedFcn = createCallbackFcn(app, @EDAArtifactsMenuSelected, true);
            app.EDAArtifactsMenu.Text = 'EDA Artifacts';
            app.ManualArtifactsMenu = uimenu(app.ProcessingMenu);
            app.ManualArtifactsMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualArtifactsMenuSelected, true);
            app.ManualArtifactsMenu.Text = 'Manual Artifacts';
            app.AlignTimeseriesMenu = uimenu(app.ProcessingMenu);
            app.AlignTimeseriesMenu.MenuSelectedFcn = createCallbackFcn(app, @AlignTimeseriesMenuSelected, true);
            app.AlignTimeseriesMenu.Text = 'Align Timeseries';
            app.RepeatedMeasuresMenu = uimenu(app.ProcessingMenu);
            app.RepeatedMeasuresMenu.MenuSelectedFcn = createCallbackFcn(app, @RepeatedMeasuresMenuSelected, true);
            app.RepeatedMeasuresMenu.Text = 'Repeated Measures';
            app.GroupingMenu = uimenu(app.ProcessingMenu);
            app.GroupingMenu.MenuSelectedFcn = createCallbackFcn(app, @GroupingMenuSelected, true);
            app.GroupingMenu.Text = 'Grouping';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pewtrcon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end